classdef AdvancedSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        dpfEditField            matlab.ui.control.EditField
        dpfEditFieldLabel       matlab.ui.control.Label
        lpfEditField            matlab.ui.control.NumericEditField
        lpfEditFieldLabel       matlab.ui.control.Label
        hpfEditField            matlab.ui.control.NumericEditField
        hpfEditFieldLabel       matlab.ui.control.Label
        hmrR_OD2COnc_newLabel   matlab.ui.control.Label
        hmrR_BandpassFiltLabel  matlab.ui.control.Label
        OkButton                matlab.ui.control.Button
        ApplyButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        cfg
        ApplyCb
        CloseCb
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cfgIn, applyCb, closeCb)
            app.cfg = cfgIn;
            app.ApplyCb = applyCb;
            app.CloseCb = closeCb;
                
            % set the values in this UI from cfg from main app
            % just for default
            app.hpfEditField.Value = app.cfg.hpf;
            app.lpfEditField.Value = app.cfg.lpf;
            app.dpfEditField.Value = sprintf('%.3f ', app.cfg.dpf);


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ApplyCb(app.cfg)

        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            ApplyButtonPushed(app,event);
            app.UIFigureCloseRequest(event);
            
        end

        % Value changed function: hpfEditField
        function hpfEditFieldValueChanged(app, event)
            value = app.hpfEditField.Value;

            if ~(isnumeric(value)) || isempty(value)
                uialert(app.UIFigure,'Please enter numeric number!', 'Error');
                app.hpfEditField.Value = [];
                return;
            else
                app.cfg.hpf = value;        
            end

        end

        % Value changed function: lpfEditField
        function lpfEditFieldValueChanged(app, event)
            value = app.lpfEditField.Value;

            if ~(isnumeric(value)) || isempty(value)
                uialert(app.UIFigure,'Please enter numeric number!', 'Error');
                app.lpfEditField.Value = [];
                return;
            else
                app.cfg.lpf = value;        
            end

        end

        % Value changed function: dpfEditField
        function dpfEditFieldValueChanged(app, event)
            rawStr = app.dpfEditField.Value;
            tokens = split(rawStr);
            parsed = str2double(tokens);

            if any(isnan(parsed)) || ~isvector(parsed)
                uialert(app.UIFigure, "Invalid input, please enter number!", 'Error');
            else
                app.cfg.dpf = parsed;
            end 

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.CloseCb)
                try
                    app.CloseCb(app.cfg);
                catch
                    % swallow to ensure window still closes
                end
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.hmrR_BandpassFiltLabel = uilabel(app.UIFigure);
            app.hmrR_BandpassFiltLabel.Text = 'hmrR_BandpassFilt';
            app.hmrR_OD2COnc_newLabel = uilabel(app.UIFigure);
            app.hmrR_OD2COnc_newLabel.Text = 'hmrR_OD2COnc_new';
            app.hpfEditFieldLabel = uilabel(app.UIFigure);
            app.hpfEditFieldLabel.Text = 'hpf';
            app.hpfEditField = uieditfield(app.UIFigure, 'numeric');
            app.hpfEditField.ValueChangedFcn = createCallbackFcn(app, @hpfEditFieldValueChanged, true);
            app.lpfEditFieldLabel = uilabel(app.UIFigure);
            app.lpfEditFieldLabel.Text = 'lpf';
            app.lpfEditField = uieditfield(app.UIFigure, 'numeric');
            app.lpfEditField.ValueChangedFcn = createCallbackFcn(app, @lpfEditFieldValueChanged, true);
            app.dpfEditFieldLabel = uilabel(app.UIFigure);
            app.dpfEditFieldLabel.Text = 'dpf';
            app.dpfEditField = uieditfield(app.UIFigure, 'text');
            app.dpfEditField.ValueChangedFcn = createCallbackFcn(app, @dpfEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvancedSetting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end