classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        ListsnrifListBox            matlab.ui.control.ListBox
        ListsnrifListBoxLabel       matlab.ui.control.Label
        EditOptionButton            matlab.ui.control.Button
        ListBoxSubject              matlab.ui.control.ListBox
        DatafilesLabel              matlab.ui.control.Label
        RunImageReconExp            matlab.ui.control.Button
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        AdvApp
        ds % data source
        cache % current temp
        sel % user selection
        cfg
    end
    
    methods (Access = private)
       
        %% function for advance settings
        function onAdvancedApply(app, newCfg) %% When user closes the adv setting window, apply changes
            app.cfg = newCfg;
        end

        function onAdvancedClose(app, newCfg)
            app.cfg = newCfg;
            if ~isempty(app.AdvApp) && isvalid (app.AdvApp)
                delete(app.AdvApp);
            end

            % clear AdvAppand re enable button
            app.AdvApp = [];
            app.EditOptionButton.Enable = "on";
        end


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)
            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            app.ds.waveLength = app.ds.snirf.probe.wavelengths;
            items = {};
            
            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
            end

            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.Value = items{1};
            drawnow;
        end
        

        %%  Parse channel label text "TxX–RxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Tx' and after 'Rx', with any non-digit separator between
            tok = regexp(label,'Tx(\d+)[^\d]+Rx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % DEFAULT DEFAULT
            appDir  = fileparts(mfilename('fullpath')); % .../SSDOT-main/GUI
            rootDir = fileparts(appDir); % .../SSDOT-main
        

            addpath(genpath(fullfile(rootDir,'code')));
            addpath(genpath(fullfile(rootDir,'homer_function')));

            % DEFAULT VAR, user can change these parameter on GUI
            app.cfg.hpf = 0.01; % default const for convert OD
            app.cfg.lpf = 0.1;

            app.cfg.dpf = [6.06, 6.06]; % for convert to Conc

            % SYNC UI

            % Other
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select, show the subjects list with folder that match the naming "Subj*"

            folderPath = uigetdir(pwd, 'Select the exp_data folder');
            
            if isequal(folderPath, 0)
                return;
            end

            app.cfg.datapath = folderPath;
            app.cfg.av_data_path = folderPath;
            app.cfg.savepath = fullfile(folderPath, 'bothConditions');
            disp(['Data folder set to: ', folderPath]);

            folders = dir(fullfile(folderPath,'Subj*'));
            names = {folders([folders.isdir]).name};
            names = setdiff(names, {'.', '..'});

            if isempty(names)
                app.ListBoxSubject.Items = {'(Not found Subj* folder)'};
            else
                app.ListBoxSubject.Items = strcat("📁 ", string(names));
            end

        end

        % Double-clicked callback: ListBoxSubject
        function ListBoxSubjectDoubleClicked(app, event)
            % When user double clicked a subject folder in the list
            % scans the subject folder for .snirf fiels
            % updates ListSnirfListBox with found SNIRF files

            rawVal  = string(app.ListBoxSubject.Value);
            subj    = regexprep(rawVal, '^\s*📁\s*', '');
        
            if ~isfield(app.cfg,'datapath') || ~isfolder(app.cfg.datapath)
                uialert(app.UIFigure,'browse to select exp_data folder first.','Eroor');
                app.ListsnrifListBox.Items = {};
                return
            end
        
            subjPath = fullfile(app.cfg.datapath, subj);
            % find all snirf files inside subject folder
            S = dir(fullfile(subjPath, '*.snirf'));
        
            if isempty(S)
                app.ListsnrifListBox.Items = {'(No .snirf found)'};
                app.ListsnrifListBox.Value = '(No .snirf found)';
            else
                app.ListsnrifListBox.Items = {S.name}; %%update snirf List Box, show all snirf files
                app.ListsnrifListBox.Value = S(1).name;
            end
            
        end

        % Double-clicked callback: ListsnrifListBox
        function ListsnrifListBoxDoubleClicked(app, event)
            % When use double click a SNIRF file
            % it will immediately load file using SnirfClass()
            %
            rawSubj   = char(app.ListBoxSubject.Value);
            subj      = regexprep(rawSubj, '^\s*📁\s*', '');
            snirfName = char(app.ListsnrifListBox.Value);
            
            filePath = fullfile(app.cfg.datapath, subj, snirfName);
            filePath = char(filePath);
            
            % % debug
            % % fprintf('Datapath = %s\n', app.cfg.datapath);
            % % fprintf('Subject = %s\n', subj);
            % % fprintf('SNIRF file = %s\n', snirfName);
            % % fprintf('Full path = %s\n', filePath);
                        
            % --- HERE, RIGHT HERE: LOAD SNIRF ---
            acquired = SnirfClass(filePath);
            app.ds.snirf = acquired;

            % debug
            % copy "acquired" from app desginer to the base workspace, so can check in the command window
            assignin('base','acquired',acquired);

            if isempty(acquired.data)
                warning('SNIRF file empty');
                uialert(app.UIFigure, 'SNIRF file is empty', 'warning');
            else
                disp('SNIRF good.');
            end

            
            % Get channel:
            ml = app.ds.snirf.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure,'measurementList is empty.','Warning');
                return;
            end

            
            %% Create arrays to store Source (Tx) and Detector (Rx) IDs
            %% Each measurement will have one Tx and one Rx, I guess
            sourceArray = zeros( numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            % Fill the arrays with IDs from each measurement
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end
 

            % Combine Tx and Rx into one nx2 array
            % make a list of UNIQUE [Tx,Rx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);
                    
                % Check if this pair is already in 'pairs'
                for j = 1:size(pairs,1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end
                end

                % If the pair is new, add it to the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            %% Create label & map, update channel to ListBox of channel
            m = size(pairs,1);
            labels = strings(m,1);
            app.ds.channelMap = struct('label',{},'s',{},'d',{});
            
            for k = 1:m
                s = pairs(k,1); 
                d = pairs(k,2);
                labels(k) = sprintf('Tx%d–Rx%d', s, d);
        
                app.ds.channelMap(k).label = labels(k);
                app.ds.channelMap(k).s = s;
                app.ds.channelMap(k).d = d;
                
                % Debug print
                fprintf('Channel %d: \n', k);
                fprintf('  s = %d, d = %d \n', s, d);
                fprintf('  label = %s \n', labels(k));
                fprintf('\n\n\n');
            end

            %% updatea channelDropDown on GUI
            app.ChannelDropDown.Items = cellstr(labels(:));   % ensure column cellstr
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};  % must exist in Items
            drawnow; % force GUI refresh to update channelDropDown


            %%update ListBox for showing like wavelength and Hbr...
            updateSignalList(app);


           app.ds.wls = app.ds.snirf.GetWls();
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            updateSignalList(app);
            
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)

            
        end

        % Clicked callback: SignalListBox
        function SignalListBoxClicked(app, event)
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            %% Basic guards
            if isempty(app.ds) || isempty(app.ds.snirf) || isempty(app.ds.snirf.data)
                uialert(app.UIFigure, 'Please load a SNIRF first.', 'Warning'); return;
            end
            if isempty(app.ChannelDropDown.Value) || startsWith(string(app.ChannelDropDown.Value),'(no ')
                uialert(app.UIFigure, 'Please select channel', 'Warning'); return;
            end
            if isempty(app.SignalListBox.Value)
                uialert(app.UIFigure, 'Please select at least one wave length or Hb type', 'Warning'); return;
            end


            % Selection from user
            chLabel = app.ChannelDropDown.Value; % 'TxX–RxY'
            [s,d]   = app.parseChannelLabel(chLabel);
            selectSignal = app.SignalListBox.Value; % e.g:'670nm'/Hbr


            % Preload raw data and time (Raw/OD use this) for plotting
            dataMat = app.ds.snirf.data.GetDataTimeSeries(); % array[N][M]
            timeVec = app.ds.snirf.data.GetTime(); % array[N][1]


            % Build arrays of source, detector, wavelength index for raw/od
            % look up column
            ml = app.ds.snirf.data.measurementList;
            numMeasurements = numel(ml);
            sourceIdx = zeros(numMeasurements, 1);
            detectorIdx = zeros(numMeasurements, 1);
            waveLengthIdx = ones(numMeasurements,1); % let be 1 for


            for i = 1:numMeasurements
                waveLengthIdx(i) = ml(i).wavelengthIndex;
                sourceIdx(i) = ml(i).sourceIndex;
                detectorIdx(i) = ml(i).detectorIndex;
            end


            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            legendLabels = {};

            switch plotType
                case 'Raw data'
                    for i = 1:numel(selectSignal)
                        % wavelength index only for raw/OD
                        wlIdx = app.nmLabelToIndex(selectSignal{i});

                        % find column for (s,d,wlIdx)
                        col = find(sourceIdx==s & detectorIdx==d & waveLengthIdx==wlIdx, 1, 'first');

                        plot(app.UIAxes, timeVec, dataMat(:,col), 'LineWidth', 1.5);
                        legendLabels{end + 1} = sprintf('Tx%d-Rx%d - %s', s, d, selectSignal{i});
                    end

                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'Intensity (a.u.)');
                        title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d', s, d));


                    case 'OD'
                        %debug
                        fprintf("hpf: %.3f \nlpf: %.3f\n", app.cfg.hpf, app.cfg.lpf);
                        % Converting intensity to optical density
                        app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);
                        % filter by using a band-pass filter
                        app.cache.dod = hmrR_BandpassFilt(app.cache.dod, app.cfg.hpf, app.cfg.lpf);

                        for i = 1:numel(selectSignal)
                            wlIdx = app.nmLabelToIndex(selectSignal{i});
                            col = find(sourceIdx == s & detectorIdx == d & waveLengthIdx == wlIdx, 1, 'first');

                            plot(app.UIAxes, app.cache.dod.time, app.cache.dod.dataTimeSeries(:,col), 'LineWidth', 1.2)
                            legendLabels{end + 1} = sprintf('Tx%d-Rx%d - %s', s, d, selectSignal{i});
                        end

                        xlabel(app.UIAxes,'Time (s)');
                        ylabel(app.UIAxes,'I dont know unit for Y-axis here');
                        title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', s, d));


                        case 'Conc'
                            %debug
                            fprintf("dpf: %.3f \n", app.cfg.dpf);
                            % Convert to optical density
                            app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);
        
                            % filter
                            app.cache.dod = hmrR_BandpassFilt(app.cache.dod, app.cfg.hpf, app.cfg.lpf);
        
                            % Convert optical density to concentration
                            dpf = app.cfg.dpf;
                            app.cache.dc = hmrR_OD2Conc(app.cache.dod, app.ds.snirf.probe, dpf);
        
                            % Find first triplet (HbO/HbR/HbT) for this source-detector pair
                            HbO_id = [];
                            for idx = 1:numel(app.cache.dc.measurementList)
                                s_id0 = app.cache.dc.measurementList(1, idx).sourceIndex;
                                d_id0 = app.cache.dc.measurementList(1, idx).detectorIndex;
                                if (s_id0 == s && d_id0 == d)
                                    HbO_id = idx;
                                    break
                                end
                            end

                            app.cache.dc.measurementList = app.cache.dc.measurementList(1, HbO_id:HbO_id+2);
                            app.cache.dc.dataTimeSeries  = app.cache.dc.dataTimeSeries(:, HbO_id:HbO_id+2);

                            for i = 1:numel(selectSignal)
                                selection = lower(selectSignal{i});
                                if strcmp(selection, 'hbo') , k = 1;
                                elseif strcmp(selection, 'hbr'), k = 2;
                                elseif strcmp(selection, 'hbt'), k = 3;
                                end

                                % Plot
                                %% y value can scale by = 1e6 * app.cache.dc.dataTimeSeries(:,k)
                                plot(app.UIAxes, app.cache.dc.time, app.cache.dc.dataTimeSeries(:, k), 'LineWidth', 1.2);
                                
                                names = {'HbO', 'HbR', 'HbT'};
                                legendLabels{end+1} = sprintf('%s — Tx%d–Rx%d', names{k}, s, d);
                            end

                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Tx%d–Rx%d', s, d));
            end

            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            legend(app.UIAxes, legendLabels, 'Location','northeast')
            grid(app.UIAxes,'on');
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: EditOptionButton
        function EditOptionButtonPushed(app, event)
            % Open the advanced settings window
            % allow user change some parameters
            % Disable the button while the dialog is open
            app.EditOptionButton.Enable = "off";
        
            % Open Advanced settings dialog
            app.AdvApp = AdvancedSetting(app.cfg, @app.onAdvancedApply, @app.onAdvancedClose);
            app.AdvApp.UIFigure.WindowStyle = 'modal';
        
            % Ensure the button is re-enabled if user closes the window via [X]
            %app.AdvApp.UIFigure.CloseRequestFcn = @(src,evt) app.onAdvClosed();

        end

        % Button pushed function: RunImageReconExp
        function RunImageReconExpButtonPushed(app, event)
            %% nothing...
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Browse';
            app.RunImageReconExp = uibutton(app.UIFigure, 'push');
            app.RunImageReconExp.ButtonPushedFcn = createCallbackFcn(app, @RunImageReconExpButtonPushed, true);
            app.RunImageReconExp.Text = 'Run reconstruct';
            app.DatafilesLabel = uilabel(app.UIFigure);
            app.DatafilesLabel.Text = 'Subjects';
            app.ListBoxSubject = uilistbox(app.UIFigure);
            app.ListBoxSubject.Items = {};
            app.ListBoxSubject.Multiselect = 'on';
            app.ListBoxSubject.DoubleClickedFcn = createCallbackFcn(app, @ListBoxSubjectDoubleClicked, true);
            app.ListBoxSubject.Value = {};
            app.EditOptionButton = uibutton(app.UIFigure, 'push');
            app.EditOptionButton.ButtonPushedFcn = createCallbackFcn(app, @EditOptionButtonPushed, true);
            app.EditOptionButton.Text = 'Edit Option';
            app.ListsnrifListBoxLabel = uilabel(app.UIFigure);
            app.ListsnrifListBoxLabel.Text = 'List snrif';
            app.ListsnrifListBox = uilistbox(app.UIFigure);
            app.ListsnrifListBox.Items = {''};
            app.ListsnrifListBox.DoubleClickedFcn = createCallbackFcn(app, @ListsnrifListBoxDoubleClicked, true);
            app.ListsnrifListBox.Value = {};
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end